classdef Interfaz_proyecto_1_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        textod    matlab.ui.control.Label
        texto     matlab.ui.control.Label
        Grupo4RafaelBurgosJocelynMatusLabel  matlab.ui.control.Label
        Image     matlab.ui.control.Image
        onoff     matlab.ui.control.Button
        text7     matlab.ui.control.Label
        edit      matlab.ui.control.EditField
        editd     matlab.ui.control.EditField
        sliderd   matlab.ui.control.Slider
        text6     matlab.ui.control.Label
        slider    matlab.ui.control.Slider
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)   %% Función comunicación PIC
        
        DutyCycle; % Property
        Frequ;     % Property
        SerPort;   % Property

        Fosc = 48*10^(6);        % Freq PIC
        Tosc = 1/(48*10^(6));    % Tmpo Oscil.
        PS_tmr2 = 4;             % Prescaler    
        
        CCPRxL;
        
        %Variables de bits fwrite Freq
        F = hex2dec('0F');       % Primer fwrite Freq 
        PR2;
        null = 0;                % Tercer fwrite Freq (8 bits de 0s)
        
        %Variables de bits fwrite Duty
        D = hex2dec('0D');       % Primer fwrite Duty
        ccpfw2;                  % Segundo fwrite Duty
        ccpfw3;                  % Tercer fwrite Duty
        
        Conectado;

        Vout;                    % Vout teórico
    end
 
     methods (Access = private)     %% Inicializa funciones
       
         function DutyCalc(app)       % Duty a enviar
          
          % 0x0D se envia en fwrite1 duty (ver funcion Serial)
             
          % Calculo CCPRxL   
          app.CCPRxL = 4*(app.PR2+1)*((app.DutyCycle)/100); % Valor DutyCycle a enviar   
             
          % Declarar uint16(*) con valor de CCPRxL // Variable 'nueva' a operar (2 bytes)
          ccpduty = uint16(app.CCPRxL);
          
          %% 2do fwrite_ducy (envía 1 byte de los 2 bytes)
          % A unint16 >>8 (hacia los LSB), enviar esos 8bits LSB
          ccp2MSB = bitsrl(ccpduty, 8); % 8MSB >>8
          
          app.ccpfw2 = ccp2MSB;
          % ccpfw2 se envia en fwrite3 duty
          
          %% 3er fwrite_ducy (meter variable 2 bytes originales, enviar 1byte)
          % Con &&0xFF, tomar los LSB, guardarlo en 8bits, enviar esos LSB 
          
          ccplsb = uint16(255);   % 255 = 00000 0000 1111 1111 en binario // Máscara a aplicar
          
          app.ccpfw3 = bitand(ccpduty, ccplsb);      %Recoge los LSB a enviar (multiplica binarios ccplsb por ccpduty)
          % ccpfw3 se envia en fwrite2 duty
          
          if app.Conectado
              
              FWrites(app);
              
          end
          
        end


         function FreqCalc(app)       % Calculo PR2,    Frecuencia a enviar
          
          % 0x0F se envia en fwrite1 freq (ver funcion Serial)   
             
          Tpwm = 1/(app.Frequ);     % Tmpo PWM   
          
          % Calculo PR2
          app.PR2 = (Tpwm/(4*(app.Tosc)*(app.PS_tmr2)))-1;
          % pr2 a enviar en fwrite2 freq
          
          % byte de 0s se envía en fwrite3 freq (ver funcion Serial)
          
          DutyCalc(app);    % Con PR2 calculado llamada a ejecutar funcion DutyCalc para calculo CCPRxL          
                
        end
             
        function FWrites(app)
            
             % Valores properties
             %Freq
             fF = app.F;
             fPR2 = app.PR2;
             fnull = app.null; 
             %Duty
             fD = app.D;
             fccpfw2 = app.ccpfw2;
             fccpfw3 = app.ccpfw3;
             serpic = serial('COM3');     % Nombre asignado al PIC
             set(serpic,'BaudRate',115200);
             fopen(serpic);
             
             %% FWRITES DE FREQ
                 %% 1er fwrite_freq (1byte)
                 % 0x0F    
                 fwrite(serpic, fF, 'uint8');   %1er fwrite
           
                 %% 2do fwrite_freq (1byte)           
                 % PR2 (1byte)  
                 fwrite(serpic, fPR2, 'uint8');          % LISTO
             
                 %% 3er fwrite_freq (1byte) 
                 % byte de 0s 
                 fwrite(serpic, fnull, 'uint8');             %LISTO            
                   
             %% FWRITES DE DUTY
                 %% 1er fwrite_ducy (1byte)
                 % 0x0D        
                 fwrite(serpic, fD, 'uint8');
                 
                 %% 2do fwrite_ducy (1byte)
                 % Envía los 8 LSB del valor del uint16 de DutyCycle
                 fwrite(serpic, fccpfw3, 'uint8');
                 
                 %% 3er fwrite_ducy (1byte)
                 % Envía los 8 MSB >>8 (MSBs hacia los LSB)
                 fwrite(serpic, fccpfw2, 'uint8');
 
             fclose(serpic);
     
        end
     end  %% Termina de declarar funciones   

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: edit
        function editValueChanged(app, event)
            frecuencia= str2double(app.edit.Value);
            duty= app.sliderd.Value;
            app.slider.Value= round(frecuencia);
            app.editd.Value=num2str(round(duty));
            largo=0:0.001:1;
            
             if (frecuencia>=5000)&&(frecuencia<=1.114285714285714e+04)
                q=square(2*pi*frecuencia*largo/500, duty);
                
            elseif (frecuencia>1.114285714285714e+04)&&(frecuencia<=1.728571428571429e+04)
                q=square(2*pi*frecuencia*largo/1000, duty);
                
            elseif (frecuencia>1.728571428571429e+04)&&(frecuencia<=2.342857142857143e+04)
                q=square(2*pi*frecuencia*largo/2000,duty);
                
            elseif (frecuencia>2.342857142857143e+04)&&(frecuencia<=2.957142857142857e+04)
                q=square(2*pi*frecuencia*largo/4000,duty);
                
            elseif (frecuencia>2.957142857142857e+04)&&(frecuencia<=3.571428571428572e+04)
                q=square(2*pi*frecuencia*largo/6000,duty);
                
            elseif (frecuencia>3.571428571428572e+04)&&(frecuencia<=4.185714285714286e+04)
                q=square(2*pi*frecuencia*largo/8000,duty);
                
            elseif (frecuencia>4.185714285714286e+04)&&(frecuencia<=48000)
                q=square(2*pi*frecuencia*largo/10000,duty);
             
             end
            
            if ((frecuencia<=30000) && (frecuencia>=10000))
                
                plot(app.UIAxes,largo,q,'y','linewidth',2);
                
                app.texto.Text = '10 [kHz] --> 30 [kHz]';
            
                app.Frequ= frecuencia;
                app.DutyCycle= duty;
                FreqCalc(app)

                
            else
                app.texto.Text = 'Por favor, ingrese un valor entre 10 [kHz] y 30 [kHz]';
            
            end
            
        end

        % Value changed function: slider
        function sliderValueChanged(app, event)
            frecuencia= app.slider.Value;
            duty= app.sliderd.Value;
            app.edit.Value= num2str(round(frecuencia));
            app.editd.Value=num2str(round(duty));
            largo=0:0.001:1;
            
             if (frecuencia>=5000)&&(frecuencia<=1.114285714285714e+04)
                q=square(2*pi*frecuencia*largo/500, duty);
                
            elseif (frecuencia>1.114285714285714e+04)&&(frecuencia<=1.728571428571429e+04)
                q=square(2*pi*frecuencia*largo/1000, duty);
                
            elseif (frecuencia>1.728571428571429e+04)&&(frecuencia<=2.342857142857143e+04)
                q=square(2*pi*frecuencia*largo/2000,duty);
                
            elseif (frecuencia>2.342857142857143e+04)&&(frecuencia<=2.957142857142857e+04)
                q=square(2*pi*frecuencia*largo/4000,duty);
                
            elseif (frecuencia>2.957142857142857e+04)&&(frecuencia<=3.571428571428572e+04)
                q=square(2*pi*frecuencia*largo/6000,duty);
                
            elseif (frecuencia>3.571428571428572e+04)&&(frecuencia<=4.185714285714286e+04)
                q=square(2*pi*frecuencia*largo/8000,duty);
                
            elseif (frecuencia>4.185714285714286e+04)&&(frecuencia<=48000)
                q=square(2*pi*frecuencia*largo/10000,duty);
             
             end
            
            if ((frecuencia<=30000) && (frecuencia>=10000))
                
                plot(app.UIAxes,largo,q,'y','linewidth',2);
                
                
                app.texto.Text = '10 [kHz] --> 30 [kHz]';
                            
                app.Frequ= frecuencia;
                app.DutyCycle= duty;
                FreqCalc(app)
                
            else
                app.texto.Text = 'Por favor, ingrese un valor entre 10 [kHz] y 30 [kHz]';
            
            end
        end

        % Value changed function: sliderd
        function sliderdValueChanged(app, event)
            frecuencia= app.slider.Value;
            duty= app.sliderd.Value;
            app.edit.Value= num2str(round(frecuencia));
            app.editd.Value=num2str(round(duty));
            largo=0:0.001:1;
            
             if (frecuencia>=5000)&&(frecuencia<=1.114285714285714e+04)
                q=square(2*pi*frecuencia*largo/500, duty);
                
            elseif (frecuencia>1.114285714285714e+04)&&(frecuencia<=1.728571428571429e+04)
                q=square(2*pi*frecuencia*largo/1000, duty);
                
            elseif (frecuencia>1.728571428571429e+04)&&(frecuencia<=2.342857142857143e+04)
                q=square(2*pi*frecuencia*largo/2000,duty);
                
            elseif (frecuencia>2.342857142857143e+04)&&(frecuencia<=2.957142857142857e+04)
                q=square(2*pi*frecuencia*largo/4000,duty);
                
            elseif (frecuencia>2.957142857142857e+04)&&(frecuencia<=3.571428571428572e+04)
                q=square(2*pi*frecuencia*largo/6000,duty);
                
            elseif (frecuencia>3.571428571428572e+04)&&(frecuencia<=4.185714285714286e+04)
                q=square(2*pi*frecuencia*largo/8000,duty);
                
            elseif (frecuencia>4.185714285714286e+04)&&(frecuencia<=48000)
                q=square(2*pi*frecuencia*largo/10000,duty);
             
             end
            
            if ((duty<=60) && (duty>=15))
                
                plot(app.UIAxes,largo,q,'y','linewidth',2);
                
                app.textod.Text = '%15% --> 60%';
            
                app.Frequ= frecuencia;
                app.DutyCycle= duty;
                FreqCalc(app)
            else
                app.textod.Text = 'Por favor, ingrese un valor entre 15% y 60%';
            
            end
            
        end

        % Value changed function: editd
        function editdValueChanged(app, event)
            frecuencia= app.slider.Value;
            duty= str2double(app.editd.Value);
            app.edit.Value= num2str(round(frecuencia));
            app.sliderd.Value=round(duty);
            largo=0:0.001:1;
            
             if (frecuencia>=5000)&&(frecuencia<=1.114285714285714e+04)
                q=square(2*pi*frecuencia*largo/500, duty);
                
            elseif (frecuencia>1.114285714285714e+04)&&(frecuencia<=1.728571428571429e+04)
                q=square(2*pi*frecuencia*largo/1000, duty);
                
            elseif (frecuencia>1.728571428571429e+04)&&(frecuencia<=2.342857142857143e+04)
                q=square(2*pi*frecuencia*largo/2000,duty);
                
            elseif (frecuencia>2.342857142857143e+04)&&(frecuencia<=2.957142857142857e+04)
                q=square(2*pi*frecuencia*largo/4000,duty);
                
            elseif (frecuencia>2.957142857142857e+04)&&(frecuencia<=3.571428571428572e+04)
                q=square(2*pi*frecuencia*largo/6000,duty);
                
            elseif (frecuencia>3.571428571428572e+04)&&(frecuencia<=4.185714285714286e+04)
                q=square(2*pi*frecuencia*largo/8000,duty);
                
            elseif (frecuencia>4.185714285714286e+04)&&(frecuencia<=48000)
                q=square(2*pi*frecuencia*largo/10000,duty);
             
             end
            
            if ((duty<=60) && (duty>=15))
                
                plot(app.UIAxes,largo,q,'y','linewidth',2);
                
                
                app.textod.Text = '%15% --> 60%';
            
                app.Frequ= frecuencia;
                app.DutyCycle= duty;
                FreqCalc(app)
            end
        end

        % Button pushed function: onoff
        function onoffButtonPushed(app, event)
            info = strcmp(app.onoff.Text,'Conectado');%string compare para poder comparar las palabras y asi modificarlas
            if    info== 1
                app.onoff.BackgroundColor= 'r';
                app.onoff.Text= 'Desconectado';
                app.Conectado= 0;
              
            elseif info == 0
                app.onoff.BackgroundColor= 'g';
                app.onoff.Text= 'Conectado';
                app.Conectado=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PWM')
            xlabel(app.UIAxes, 'Ciclo')
            ylabel(app.UIAxes, 'Voltaje')
            app.UIAxes.YLim = [0 1.1];
            app.slider = uislider(app.UIFigure);
            app.slider.Limits = [10000 30000];
            app.slider.MajorTicks = [10000 15000 20000 25000 30000];
            app.slider.MajorTickLabels = {'10k', '15k', '20k', '25k', '30k'};
            app.slider.ValueChangedFcn = createCallbackFcn(app, @sliderValueChanged, true);
            app.slider.Tag = 'slider';
            app.slider.Value = 20000;
            app.text6 = uilabel(app.UIFigure);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Frecuencia';
            app.sliderd = uislider(app.UIFigure);
            app.sliderd.Limits = [15 60];
            app.sliderd.MajorTicks = [15 25 35 45 55 60];
            app.sliderd.MajorTickLabels = {'15%', '25%', '35%', '45%', '55%', '60%'};
            app.sliderd.ValueChangedFcn = createCallbackFcn(app, @sliderdValueChanged, true);
            app.sliderd.Tag = 'sliderd';
            app.sliderd.Value = 32;
            app.editd = uieditfield(app.UIFigure, 'text');
            app.editd.ValueChangedFcn = createCallbackFcn(app, @editdValueChanged, true);
            app.editd.Tag = 'editd';
            app.editd.Value = '60';
            app.edit = uieditfield(app.UIFigure, 'text');
            app.edit.ValueChangedFcn = createCallbackFcn(app, @editValueChanged, true);
            app.edit.Tag = 'edit';
            app.edit.Value = '20000';
            app.text7 = uilabel(app.UIFigure);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Duty Cicle';
            app.onoff = uibutton(app.UIFigure, 'push');
            app.onoff.ButtonPushedFcn = createCallbackFcn(app, @onoffButtonPushed, true);
            app.onoff.Tag = 'onoff';
            app.onoff.Text = 'Apagado';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Escudo_udec.gif';
            app.Grupo4RafaelBurgosJocelynMatusLabel = uilabel(app.UIFigure);
            app.Grupo4RafaelBurgosJocelynMatusLabel.Text = {'Grupo 4: '; '- Rafael Burgos'; '- Jocelyn Matus'};
            app.texto = uilabel(app.UIFigure);
            app.texto.Tag = 'texto';
            app.texto.Text = '10 [kHz] --> 30 [kHz]';
            app.textod = uilabel(app.UIFigure);
            app.textod.Tag = 'textod';
            app.textod.Text = '%15% --> 60%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_proyecto_1_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end